classdef createMask < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        IndexSpinnerLabel      matlab.ui.control.Label
        IndexSpinner           matlab.ui.control.Spinner
        AxisButtonGroup        matlab.ui.container.ButtonGroup
        XYButton               matlab.ui.control.RadioButton
        XZButton               matlab.ui.control.RadioButton
        YZButton               matlab.ui.control.RadioButton
        MaskButton             matlab.ui.control.Button
        SaveButton             matlab.ui.control.Button
        ManualOversightButton  matlab.ui.control.Button
        FitButton              matlab.ui.control.Button
        ShowDataButton         matlab.ui.control.Button
    end

    
    properties (Access = public)
        data % Description
        axis
        roi
        h
        ax
        positions
        mask 
        sliceData
        c_data
        oversight
        fs
        L
        c
        scale_mm_per_voxel
        i
        j
        dirname
    end
    
    methods (Access = private)
        
        function sliced_data = slice_data(app)
            if app.axis == 1
                sliced_data = squeeze(app.data(app.IndexSpinner.Value, :, :));
            elseif app.axis == 2
                sliced_data = squeeze(app.data(:, app.IndexSpinner.Value, :));
            elseif app.axis == 3 
                sliced_data = squeeze(app.data(:, :, app.IndexSpinner.Value));
            end
        end
    
        
        function plotData(app)
            app.sliceData =  slice_data(app);
            imagesc(app.UIAxes, app.sliceData);
            pos = app.roi.Position;
            imagesc(app.ax, app.sliceData);
            app.roi = images.roi.Polygon(app.ax,'Position', pos);
            axis([app.UIAxes, app.ax],'square');
        end
        
        function plotPolygon(app)
            app.roi = images.roi.Polygon(app.UIAxes);
        end
        
        
        function app = updateijCoordinateOrder(app)
            try 
                [ii, jj] = getPlaneCoordinates(app.data, app.IndexSpinner.Value);
                center = round(size(squeeze(app.data(:,:,1)))/2);
                [~, order] = ijToPolarCoords(ii,jj, center);
                app.i = ii(order); app.j = jj(order);
            catch
                disp('failed updateijCoordinateOrder');
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, V, c_data, dirname)
            app.data = V;
            app.mask = ones(size(V));
            app.dirname = dirname;
            app.XYButton.Value = true;
            app.axis = 3;
            app.IndexSpinner.Value = 1;
            assignin('base','app',app);
            app.h = figure;
            imagesc(app.data(:,:,1));
            app.positions = [10 15; 20 15; 20 35; 10 35 ];
            app.ax = findall(app.h, 'type', 'axes');
            set(app.h,'Position',[100 470 520 520])
            app.roi = images.roi.Polygon(app.ax,'Position',app.positions);
            app.c_data = c_data;
            app.oversight.current_i = 1; app.oversight.updateEchoFlag=0;
            plotData(app)
            app.fs = c_data(1).fs;
            app.L = c_data(1).L;
            app.scale_mm_per_voxel = evalin('base','scale_mm_per_voxel');
            app.c = c_data(1).c;
            updateijCoordinateOrder(app)
            assignin('base','app',app);
        end

        % Value changed function: IndexSpinner
        function IndexSpinnerValueChanged(app, event)
            if app.IndexSpinner.Value >0
                if app.IndexSpinner.Value <= size(app.data, app.axis)
                    plotData(app)
                    app = updateijCoordinateOrder(app);
                end
            end
        end

        % Selection changed function: AxisButtonGroup
        function AxisButtonGroupSelectionChanged(app, event)
            selectedButton = app.AxisButtonGroup.SelectedObject;
            disp(selectedButton.Text);
            if strcmp(selectedButton.Text, 'XY')
                app.axis = 3;
            elseif strcmp(selectedButton.Text, 'XZ')
                
                app.axis = 2;
            else
                app.axis = 1;
            end
            plotData(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            delete(app)
            
            
        end

        % Button pushed function: MaskButton
        function MaskButtonPushed(app, event)
            app.positions = app.roi.Position;
            plane = squeeze(app.data(:,:,app.IndexSpinner.Value));
            idx = find(plane>0);
            [ii, jj] = ind2sub(size(plane), idx);
            [in,~] = inpolygon(ii,jj, app.positions(:,2), app.positions(:,1));
            in_idx = find(in);
            for m = 1:length(in_idx)           
                ijk = [ii(in_idx(m)), jj(in_idx(m)), app.IndexSpinner.Value];
                [~,c_data_i, tx_i] = findDataFromIdx(app.c_data, ijk);
            
                app.data(ijk(1), ijk(2), ijk(3)) = 0;
                app.mask(ijk(1), ijk(2), ijk(3)) = 0;
                if tx_i <= app.c_data(c_data_i).TxEvents/2
                    app.c_data(c_data_i).masked(1:round(app.c_data(c_data_i).TxEvents/2)) = 1;
                else
                    app.c_data(c_data_i).masked(round(app.c_data(c_data_i).TxEvents/2)+1:end) = 1;
                end
            end
                
        
            plotData(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            assignin('base','mask_data', app.data);
            assignin('base','mask', app.mask);
            assignin('base','c_data_oversight',app.c_data);
            assignin('base','V_oversight', app.data);
            if ~exist(app.dirname, 'dir')
                mkdir(app.dirname)
            end
            V_data = app.data;
            mask = app.mask;
            save_meta_data(app.c_data, [app.dirname, '\meta_data.mat']);
            save([app.dirname,'\volume_data.mat'],"V_data");
            save([app.dirname,'\mask_data.mat'], "mask");
        end

        % Button pushed function: ManualOversightButton
        function ManualOversightButtonPushed(app, event)
                      % Button pushed function: Button
           
          ff = @(h, e) manualOversight(app, h, e);
          h1 = figure('KeyPressFcn', ff, 'Name', 'Key Press Figure'); % new traditional figure with interactive features
          app.oversight.figure_h=h1;
           
          
            function manualOversight(appl, h, e)
              if appl.oversight.updateEchoFlag
                  appl.c_data(appl.oversight.c_data_i).echo_i(appl.oversight.tx_i) = round(appl.oversight.roi1.Position(1));
                  appl.oversight.updateEchoFlag = 0;
                  disp("Called Update Echo Flag");
              end
              if ~isempty(appl.i)
                  [c_data_i, tx_i] = getDataFromCurrent_i(appl.i,appl.j,appl);
                  appl.oversight.c_data_i = c_data_i;
                  appl.oversight.tx_i = tx_i;
                  appl = updateAppData(appl, c_data_i, tx_i);
                  appl = updateijCoordinateOrder(appl);
                  appl = ShowImageProcessing(appl.c_data,c_data_i, tx_i, appl.oversight.figure_h, appl);
              end
              curr_point = zeros(size(appl.sliceData));
              curr_point(appl.i(appl.oversight.current_i), appl.j(appl.oversight.current_i)) = 1;
              plotData(appl)
              imagesc(appl.UIAxes, appl.sliceData + curr_point);
              
  
              if strcmp(e.Key, 'uparrow')
                  appl.IndexSpinner.Value = appl.IndexSpinner.Value + 1;
                  IndexSpinnerValueChanged(appl, e)
              elseif strcmp(e.Key, 'downarrow')
                  appl.IndexSpinner.Value = appl.IndexSpinner.Value - 1;
                  IndexSpinnerValueChanged(appl, e)
              elseif strcmp(e.Key, 'rightarrow')
                  appl.oversight.current_i = appl.oversight.current_i+1;
                  if appl.oversight.current_i > length(appl.i)
                      appl.oversight.current_i = 1;
                  end
                  [c_data_i, tx_i] = getDataFromCurrent_i(appl.i,appl.j,appl);
              elseif strcmp(e.Key, 'leftarrow')
                  appl.oversight.current_i = appl.oversight.current_i - 1;
                  if appl.oversight.current_i < 1
                      appl.oversight.current_i = length(appl.i);
                  end
                  [c_data_i, tx_i] = getDataFromCurrent_i(appl.i,appl.j,appl);
              elseif strcmp(e.Key, 'a')
                  appl = selectNextPeak(appl, c_data_i, tx_i, -1);
              elseif strcmp(e.Key, 's')
                  appl = selectNextPeak(appl, c_data_i, tx_i, 1);
              elseif strcmp(e.Key, 'space')
                  appl.oversight.updateEchoFlag=1;
                  appl.c_data(appl.oversight.c_data_i).echo_i(appl.oversight.tx_i) = round(appl.oversight.roi1.Position(1));
              end
              appl = updateAppData(appl, c_data_i, tx_i);
              appl = updateijCoordinateOrder(appl);
              plotData(appl)
              curr_point = zeros(size(appl.sliceData));
              curr_point(appl.i(appl.oversight.current_i),appl.j(appl.oversight.current_i)) = 1;
              imagesc(appl.UIAxes, appl.sliceData + curr_point);
              appl = ShowImageProcessing(appl.c_data,c_data_i, tx_i, appl.oversight.figure_h, appl); 
              
          end
        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
            
        end

        % Button pushed function: ShowDataButton
        function ShowDataButtonPushed(app, event)
            app.positions = app.roi.Position;
            [i,j] = getPlaneCoordinates(app.data, app.IndexSpinner.Value);
            [in,~] = inpolygon(i,j, app.positions(:,2), app.positions(:,1));
            disp('pushed show data');
            if ~isempty(in) && sum(in) == 1              
                ijk = [i(in), j(in), app.IndexSpinner.Value];
                disp(ijk)
                [~,c_data_i, tx_i] = findDataFromIdx(app.c_data, ijk);
                %showImageProcessing(app.c_data, c_data_i, tx_i)
                disp('here');
                showSpeedUpCalculation(app.c_data, c_data_i, tx_i);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.IndexSpinnerLabel = uilabel(app.UIFigure);
            app.IndexSpinnerLabel.Text = 'Index';
            app.IndexSpinner = uispinner(app.UIFigure);
            app.IndexSpinner.ValueChangedFcn = createCallbackFcn(app, @IndexSpinnerValueChanged, true);
            app.AxisButtonGroup = uibuttongroup(app.UIFigure);
            app.AxisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AxisButtonGroupSelectionChanged, true);
            app.AxisButtonGroup.Title = 'Axis';
            app.XYButton = uiradiobutton(app.AxisButtonGroup);
            app.XYButton.Text = 'XY';
            app.XYButton.Value = true;
            app.XZButton = uiradiobutton(app.AxisButtonGroup);
            app.XZButton.Text = 'XZ';
            app.YZButton = uiradiobutton(app.AxisButtonGroup);
            app.YZButton.Text = 'YZ';
            app.MaskButton = uibutton(app.UIFigure, 'push');
            app.MaskButton.ButtonPushedFcn = createCallbackFcn(app, @MaskButtonPushed, true);
            app.MaskButton.Text = 'Mask';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ManualOversightButton = uibutton(app.UIFigure, 'push');
            app.ManualOversightButton.ButtonPushedFcn = createCallbackFcn(app, @ManualOversightButtonPushed, true);
            app.ManualOversightButton.Text = 'Manual Oversight';
            app.FitButton = uibutton(app.UIFigure, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Text = 'Fit';
            app.ShowDataButton = uibutton(app.UIFigure, 'push');
            app.ShowDataButton.ButtonPushedFcn = createCallbackFcn(app, @ShowDataButtonPushed, true);
            app.ShowDataButton.Text = 'Show Data';
        end
    end

    methods (Access = public)

        % Construct app
        function app = createMask(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end