classdef MechanicalScan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        YButton                 matlab.ui.control.Button
        YButton_2               matlab.ui.control.Button
        XButton                 matlab.ui.control.Button
        XButton_2               matlab.ui.control.Button
        XEditFieldLabel         matlab.ui.control.Label
        XEditField              matlab.ui.control.NumericEditField
        YEditFieldLabel         matlab.ui.control.Label
        YEditField              matlab.ui.control.NumericEditField
        IncrementSliderLabel    matlab.ui.control.Label
        IncrementSlider         matlab.ui.control.Slider
        LinearScanPanel         matlab.ui.container.Panel
        PerformScanButton       matlab.ui.control.StateButton
        StartEditFieldLabel     matlab.ui.control.Label
        StartEditField          matlab.ui.control.NumericEditField
        StopEditFieldLabel      matlab.ui.control.Label
        StopEditField           matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel  matlab.ui.control.Label
        StepSizeEditField       matlab.ui.control.NumericEditField
        NStepsEditFieldLabel    matlab.ui.control.Label
        NStepsEditField         matlab.ui.control.NumericEditField
        CircularScanPanel       matlab.ui.container.Panel
        PerformScanButton_2     matlab.ui.control.StateButton
        ConnectArduinoButton    matlab.ui.control.Button
    end

    
    properties (Access = public)
        connected_arduino % Description
        x_pos
        x_step_pin
        x_dir_pin
        y_pos
        y_step_pin
        y_dir_pin
        theta
        theta_step_pin
        theta_dir_pin
        increment 
        x_scale
        y_scale
        
        linear_scan
        
        
    end
    
    methods (Access = private)
        
        function update_textfields(app)
            app.XEditField.Value = app.x_pos;
            app.YEditField.Value = app.y_pos;
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.x_step_pin = 'D5';
            app.x_dir_pin = 'D2';
            app.y_step_pin = 'D13';
            app.y_dir_pin = 'D12';
            app.theta_step_pin = 'D10';
            app.theta_dir_pin = 'D9';
            
            app.x_pos = 0;
            app.y_pos = 0;
            app.x_scale = 5/200; % Calibrate mm per step by measuring length over 1 revolution
            app.y_scale = 5/200; % [mm/step]
            
            app.IncrementSlider.Limits = [1,200];
            app.increment = 20;
            app.IncrementSlider.Value = app.increment;
            app.IncrementSliderLabel.Text = strcat(['Increment: ',num2str(app.increment),' Steps']);
            
            app.XEditFieldLabel.Text = 'X (mm): ';
            app.YEditFieldLabel.Text = 'Y (mm): ';
            
            app.linear_scan = struct();
            app.linear_scan.start = 0;
            app.linear_scan.stop = 0;
            app.linear_scan.steps = 20;
            app.linear_scan.step_size = 1;
        end

        % Button pushed function: ConnectArduinoButton
        function ConnectArduinoButtonPushed(app, event)
            try
                app.ConnectArduinoButton.BackgroundColor = [1, 1, 1]/2;
                app.connected_arduino = arduino();
                app.ConnectArduinoButton.BackgroundColor = [0, 0, 1];
            catch e
                %Display error message
                app.ConnectArduinoButton.BackgroundColor = [1, 0, 0];
            end
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            app.YButton.BackgroundColor = [0 0 1];
            stepper_motor_inc(app.increment,app.connected_arduino, app.y_dir_pin, app.y_step_pin, 1);
            app.y_pos = app.y_pos + app.increment*app.y_scale; 
            app.YButton.BackgroundColor = [0.96 0.96 0.96];
            update_textfields(app)
        end

        % Button pushed function: YButton_2
        function YButton_2Pushed(app, event)
            app.YButton_2.BackgroundColor = [0 0 1];
            stepper_motor_inc(app.increment,app.connected_arduino, app.y_dir_pin, app.y_step_pin, 0);
            app.y_pos = app.y_pos - app.increment*app.y_scale; 
            app.YButton_2.BackgroundColor = [0.96 0.96 0.96];
            update_textfields(app)
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            app.XButton.BackgroundColor = [0 0 1];
            stepper_motor_inc(app.increment,app.connected_arduino, app.x_dir_pin, app.x_step_pin, 1);
            app.x_pos = app.x_pos + app.increment*app.x_scale; 
            app.XButton.BackgroundColor = [0.96 0.96 0.96];
            update_textfields(app)
        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            app.XButton_2.BackgroundColor = [0 0 1];
            stepper_motor_inc(app.increment,app.connected_arduino, app.x_dir_pin, app.x_step_pin, 0);
            app.x_pos = app.x_pos - app.increment*app.x_scale;
            app.XButton_2.BackgroundColor = [0.96 0.96 0.96];
            update_textfields(app)
        end

        % Value changed function: IncrementSlider
        function IncrementSliderValueChanged(app, event)
            value = app.IncrementSlider.Value;
            ticks = [app.IncrementSlider.Limits(1):app.IncrementSlider.Limits(2)];
            [~,i] = min(abs(ticks-value));
            app.IncrementSlider.Value = ticks(i);
            app.increment = app.IncrementSlider.Value;
            app.IncrementSliderLabel.Text = strcat(['Increment: ', num2str(app.increment), ' Steps']);
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            value = app.XEditField.Value;
            app.x_pos = value;
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            value = app.YEditField.Value;
            app.y_pos = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.YButton = uibutton(app.UIFigure, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = 'Y+';
            app.YButton_2 = uibutton(app.UIFigure, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YButton_2Pushed, true);
            app.YButton_2.Text = 'Y-';
            app.XButton = uibutton(app.UIFigure, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X+';
            app.XButton_2 = uibutton(app.UIFigure, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = 'X-';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X:';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y:';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.IncrementSliderLabel = uilabel(app.UIFigure);
            app.IncrementSliderLabel.Text = 'Increment: ';
            app.IncrementSlider = uislider(app.UIFigure);
            app.IncrementSlider.ValueChangedFcn = createCallbackFcn(app, @IncrementSliderValueChanged, true);
            app.LinearScanPanel = uipanel(app.UIFigure);
            app.LinearScanPanel.Title = 'Linear Scan';
            app.PerformScanButton = uibutton(app.LinearScanPanel, 'state');
            app.PerformScanButton.Text = 'Perform Scan';
            app.StartEditFieldLabel = uilabel(app.LinearScanPanel);
            app.StartEditFieldLabel.Text = 'Start:';
            app.StartEditField = uieditfield(app.LinearScanPanel, 'numeric');
            app.StopEditFieldLabel = uilabel(app.LinearScanPanel);
            app.StopEditFieldLabel.Text = 'Stop:';
            app.StopEditField = uieditfield(app.LinearScanPanel, 'numeric');
            app.StepSizeEditFieldLabel = uilabel(app.LinearScanPanel);
            app.StepSizeEditFieldLabel.Text = 'Step Size:';
            app.StepSizeEditField = uieditfield(app.LinearScanPanel, 'numeric');
            app.NStepsEditFieldLabel = uilabel(app.LinearScanPanel);
            app.NStepsEditFieldLabel.Text = 'N Steps:';
            app.NStepsEditField = uieditfield(app.LinearScanPanel, 'numeric');
            app.CircularScanPanel = uipanel(app.UIFigure);
            app.CircularScanPanel.Title = 'Circular Scan';
            app.PerformScanButton_2 = uibutton(app.CircularScanPanel, 'state');
            app.PerformScanButton_2.Text = 'Perform Scan';
            app.ConnectArduinoButton = uibutton(app.UIFigure, 'push');
            app.ConnectArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectArduinoButtonPushed, true);
            app.ConnectArduinoButton.Text = 'Connect Arduino';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MechanicalScan

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end