classdef MechanicalScan11 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        YButton                  matlab.ui.control.Button
        YButton_2                matlab.ui.control.Button
        XButton                  matlab.ui.control.Button
        XButton_2                matlab.ui.control.Button
        XEditFieldLabel          matlab.ui.control.Label
        XEditField               matlab.ui.control.NumericEditField
        YEditFieldLabel          matlab.ui.control.Label
        YEditField               matlab.ui.control.NumericEditField
        IncrementSliderLabel     matlab.ui.control.Label
        IncrementSlider          matlab.ui.control.Slider
        OneDimensionalScanPanel  matlab.ui.container.Panel
        PerformScanButton        matlab.ui.control.StateButton
        StartEditFieldLabel      matlab.ui.control.Label
        StartEditField           matlab.ui.control.NumericEditField
        StopEditFieldLabel       matlab.ui.control.Label
        StopEditField            matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel   matlab.ui.control.Label
        StepSizeEditField        matlab.ui.control.NumericEditField
        NStepsEditFieldLabel     matlab.ui.control.Label
        NStepsEditField          matlab.ui.control.NumericEditField
        AngledegreesLabel        matlab.ui.control.Label
        AxisDropDownLabel        matlab.ui.control.Label
        AxisDropDown             matlab.ui.control.DropDown
        ConnectArduinoButton     matlab.ui.control.Button
        AngledegreesLabel_2      matlab.ui.control.Label
        ThetaEditFieldLabel      matlab.ui.control.Label
        ThetaEditField           matlab.ui.control.NumericEditField
        ThetaButton              matlab.ui.control.Button
        ThetaButton_2            matlab.ui.control.Button
        PDEditFieldLabel         matlab.ui.control.Label
        PDEditField              matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        connected_arduino % Description
        x_pos
        x_step_pin
        x_dir_pin
        y_pos
        y_step_pin
        y_dir_pin
        theta
        theta_step_pin
        theta_dir_pin
        increment 
        x_scale
        y_scale
        theta_scale
        direction
        one_d_scan
        pd
    end
    
    methods (Access = private)
        
        function update_textfields(app)
            app.XEditField.Value = app.x_pos;
            app.YEditField.Value = app.y_pos;
            app.ThetaEditField.Value = app.theta;
            
            
        end
        
        function get_step_size(app, value)
            if strcmp(app.one_d_scan.axis,'X')
                app.one_d_scan.step_size_n = floor(value/app.x_scale);
                value = app.one_d_scan.step_size_n*app.x_scale;
            elseif strcmp(app.one_d_scan.axis,'Y')
                app.one_d_scan.step_size_n = floor(value/app.y_scale);
                value = app.one_d_scan.step_size_n*app.y_scale;
            else
                app.one_d_scan.step_size_n = floor(value/app.theta_scale);
                value = app.one_d_scan.step_size_n * app.theta_scale;
            end
            app.one_d_scan.step_size = value;
           
        end
        
        function get_positions(app)
            app.one_d_scan.positions = zeros([1,app.one_d_scan.steps]);
            for i = 1:app.one_d_scan.steps
                app.one_d_scan.positions(i) = app.one_d_scan.start + (i-1)*app.one_d_scan.step_size;
            end
            app.direction = (app.one_d_scan.stop -  app.one_d_scan.start) > 0;
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.x_step_pin = 'D6';
            app.x_dir_pin = 'D7';
            
            app.y_step_pin = 'D11';
            app.y_dir_pin = 'D12';
            
            app.theta_step_pin = 'D2';
            app.theta_dir_pin = 'D3';
            
            app.x_pos = 0;
            app.y_pos = 0;
            app.theta = 0;
            app.x_scale = 7.82/200; % Calibrate mm per step by measuring length over 1 revolution
            app.y_scale = 5/200; % [mm/step]
            app.theta_scale = 360/200; % Degrees per step
            
            app.IncrementSlider.Limits = [1,200];
            app.increment = 20;
            app.IncrementSlider.Value = app.increment;
            app.IncrementSliderLabel.Text = strcat(['Increment: ',num2str(app.increment),' Steps']);
            
            app.XEditFieldLabel.Text = 'X (mm): ';
            app.YEditFieldLabel.Text = 'Y (mm): ';
            
            app.one_d_scan = struct();
            app.one_d_scan.start = 0;
            app.one_d_scan.stop = 0;
            app.one_d_scan.steps = 20;
            app.one_d_scan.step_size = 1;
            app.one_d_scan.axis = 'X';
            app.AngledegreesLabel.Text = 'Position (mm)';
            app.pd = 10000;
        end

        % Button pushed function: ConnectArduinoButton
        function ConnectArduinoButtonPushed(app, event)
            try
                if ~isempty(instrfind)
                    fclose(instrfind);
                    delete(instrfind);
                end
                app.ConnectArduinoButton.BackgroundColor = [1, 1, 1]/2;
                app.connected_arduino = serial('/dev/cu.usbmodem1421','BaudRate',9600);%'COM4'('/dev/cu.usbmodem1421','BaudRate',9600);
                fopen(app.connected_arduino);
                pause(2);
                app.ConnectArduinoButton.BackgroundColor = [0, 0, 1];
            catch e
                %Display error message
                app.ConnectArduinoButton.BackgroundColor = [1, 0, 0];
                disp(e.message);
            end
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            app.YButton.BackgroundColor = [0 0 1];
            stepper_motor_inc(app.increment,app.connected_arduino, app.y_dir_pin, app.y_step_pin, 1);
            app.y_pos = app.y_pos + app.increment*app.y_scale; 
            app.YButton.BackgroundColor = [0.96 0.96 0.96];
            update_textfields(app)
        end

        % Button pushed function: YButton_2
        function YButton_2Pushed(app, event)
            app.YButton_2.BackgroundColor = [0 0 1];
            stepper_motor_inc(app.increment,app.connected_arduino, app.y_dir_pin, app.y_step_pin, 0);
            app.y_pos = app.y_pos - app.increment*app.y_scale; 
            app.YButton_2.BackgroundColor = [0.96 0.96 0.96];
            update_textfields(app)
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            app.XButton.BackgroundColor = [0 0 1];
            stepper_motor_inc(app.increment,app.connected_arduino, app.x_dir_pin, app.x_step_pin, 0);
            app.x_pos = app.x_pos + app.increment*app.x_scale; 
            app.XButton.BackgroundColor = [0.96 0.96 0.96];
            update_textfields(app)
        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            app.XButton_2.BackgroundColor = [0 0 1];
            stepper_motor_inc(app.increment,app.connected_arduino, app.x_dir_pin, app.x_step_pin, 1);
            app.x_pos = app.x_pos - app.increment*app.x_scale;
            app.XButton_2.BackgroundColor = [0.96 0.96 0.96];
            update_textfields(app)
        end

        % Value changed function: IncrementSlider
        function IncrementSliderValueChanged(app, event)
            value = app.IncrementSlider.Value;
            ticks = [app.IncrementSlider.Limits(1):app.IncrementSlider.Limits(2)];
            [~,i] = min(abs(ticks-value));
            app.IncrementSlider.Value = ticks(i);
            app.increment = app.IncrementSlider.Value;
            app.IncrementSliderLabel.Text = strcat(['Increment: ', num2str(app.increment), ' Steps']);
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            app.x_pos = app.XEditField.Value;
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            app.y_pos = app.YEditField.Value;
        end

        % Value changed function: StartEditField
        function StartEditFieldValueChanged(app, event)
            app.one_d_scan.start = app.StartEditField.Value;
        end

        % Value changed function: StopEditField
        function StopEditFieldValueChanged(app, event)
            app.one_d_scan.stop = app.StopEditField.Value;
        end

        % Value changed function: StepSizeEditField
        function StepSizeEditFieldValueChanged(app, event)
            value = app.StepSizeEditField.Value;
            get_step_size(app, value);
            app.one_d_scan.steps = floor(abs(app.one_d_scan.stop-app.one_d_scan.start)/value);
            app.StepSizeEditField.Value = app.one_d_scan.step_size;
            app.NStepsEditField.Value = app.one_d_scan.steps;
        end

        % Value changed function: NStepsEditField
        function NStepsEditFieldValueChanged(app, event)
            app.one_d_scan.steps = app.NStepsEditField.Value;
            value = abs(app.one_d_scan.stop - app.one_d_scan.start)/app.one_d_scan.steps; 
            get_step_size(app, value);
            app.StepSizeEditField.Value = app.one_d_scan.step_size;
        end

        % Value changed function: PerformScanButton
        function PerformScanButtonValueChanged(app, event)
            get_positions(app)
            %Move to first postion
            if strcmp(app.one_d_scan.axis,'X')
                current_pos = app.x_pos;
                dir_pin = app.x_dir_pin; step_pin = app.x_step_pin;
                scale = app.x_scale;
            elseif strcmp(app.one_d_scan.axis, 'Y')
                current_pos = app.y_pos;
                dir_pin = app.y_dir_pin; step_pin = app.y_step_pin;
                scale = app.y_scale;
            else
                current_pos = app.theta;
                dir_pin = app.theta_dir_pin; step_pin = app.theta_step_pin;
                scale = app.theta_scale;
            end
                
            d_steps = floor(abs(current_pos-app.one_d_scan.start)/scale);
            dir = app.one_d_scan.start - current_pos > 0;
            stepper_motor_inc(d_steps,app.connected_arduino, dir_pin, step_pin, dir);
            
            if strcmp(app.one_d_scan.axis, 'X')
                app.x_pos = app.x_pos + sign(app.one_d_scan.start - current_pos)*d_steps*scale;
            elseif strcmp(app.one_d_scan.axis, 'Y')
                app.y_pos = app.y_pos + sign(app.one_d_scan.start - current_pos)*d_steps*scale;
            else
                app.theta = app.theta + sign(app.one_d_scan.start - current_pos)*d_steps*scale;
            end
            % Run scan
            TxRx_mechanical_scan(app);
            
        end

        % Value changed function: AxisDropDown
        function AxisDropDownValueChanged(app, event)
            app.one_d_scan.axis = app.AxisDropDown.Value;
            if strcmp(app.one_d_scan.axis, 'Theta')
                app.AngledegreesLabel.Text = 'Angle (degrees)';
            else
                app.AngledegreesLabel.Text = 'Position (mm)';
            end
        end

        % Button pushed function: ThetaButton
        function ThetaButtonPushed(app, event)
            app.ThetaButton.BackgroundColor = [0 0 1];
            stepper_motor_inc(app.increment,app.connected_arduino, app.theta_dir_pin, app.theta_step_pin, 1, app.pd);
            app.theta = app.theta + app.increment*app.theta_scale; 
            app.ThetaButton.BackgroundColor = [0.96 0.96 0.96];
            update_textfields(app)
        end

        % Button pushed function: ThetaButton_2
        function ThetaButton_2Pushed(app, event)
            app.ThetaButton_2.BackgroundColor = [0 0 1];
            stepper_motor_inc(app.increment,app.connected_arduino, app.theta_dir_pin, app.theta_step_pin,0, app.pd);
            app.theta = app.theta - app.increment*app.theta_scale; 
            app.ThetaButton_2.BackgroundColor = [0.96 0.96 0.96];
            update_textfields(app)
        end

        % Value changed function: ThetaEditField
        function ThetaEditFieldValueChanged(app, event)
            app.theta = app.ThetaEditField.Value;           
        end

        % Value changed function: PDEditField
        function PDEditFieldValueChanged(app, event)
            app.pd = app.PDEditField.Value;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
            fclose(app.connected_arduino);
            catch
            end
            delete(instrfind);
            delete(app);
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.YButton = uibutton(app.UIFigure, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = 'Y+';
            app.YButton_2 = uibutton(app.UIFigure, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YButton_2Pushed, true);
            app.YButton_2.Text = 'Y-';
            app.XButton = uibutton(app.UIFigure, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X+';
            app.XButton_2 = uibutton(app.UIFigure, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = 'X-';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X:';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y:';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.IncrementSliderLabel = uilabel(app.UIFigure);
            app.IncrementSliderLabel.Text = 'Increment: ';
            app.IncrementSlider = uislider(app.UIFigure);
            app.IncrementSlider.ValueChangedFcn = createCallbackFcn(app, @IncrementSliderValueChanged, true);
            app.OneDimensionalScanPanel = uipanel(app.UIFigure);
            app.OneDimensionalScanPanel.Title = 'One Dimensional Scan';
            app.PerformScanButton = uibutton(app.OneDimensionalScanPanel, 'state');
            app.PerformScanButton.ValueChangedFcn = createCallbackFcn(app, @PerformScanButtonValueChanged, true);
            app.PerformScanButton.Text = 'Perform Scan';
            app.StartEditFieldLabel = uilabel(app.OneDimensionalScanPanel);
            app.StartEditFieldLabel.Text = 'Start:';
            app.StartEditField = uieditfield(app.OneDimensionalScanPanel, 'numeric');
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @StartEditFieldValueChanged, true);
            app.StopEditFieldLabel = uilabel(app.OneDimensionalScanPanel);
            app.StopEditFieldLabel.Text = 'Stop:';
            app.StopEditField = uieditfield(app.OneDimensionalScanPanel, 'numeric');
            app.StopEditField.ValueChangedFcn = createCallbackFcn(app, @StopEditFieldValueChanged, true);
            app.StepSizeEditFieldLabel = uilabel(app.OneDimensionalScanPanel);
            app.StepSizeEditFieldLabel.Text = 'Step Size:';
            app.StepSizeEditField = uieditfield(app.OneDimensionalScanPanel, 'numeric');
            app.StepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @StepSizeEditFieldValueChanged, true);
            app.NStepsEditFieldLabel = uilabel(app.OneDimensionalScanPanel);
            app.NStepsEditFieldLabel.Text = 'N Steps:';
            app.NStepsEditField = uieditfield(app.OneDimensionalScanPanel, 'numeric');
            app.NStepsEditField.ValueChangedFcn = createCallbackFcn(app, @NStepsEditFieldValueChanged, true);
            app.AngledegreesLabel = uilabel(app.OneDimensionalScanPanel);
            app.AngledegreesLabel.Text = 'Angle (degrees)';
            app.AxisDropDownLabel = uilabel(app.OneDimensionalScanPanel);
            app.AxisDropDownLabel.Text = 'Axis';
            app.AxisDropDown = uidropdown(app.OneDimensionalScanPanel);
            app.AxisDropDown.Items = {'X', 'Y', 'Theta', ''};
            app.AxisDropDown.ValueChangedFcn = createCallbackFcn(app, @AxisDropDownValueChanged, true);
            app.AxisDropDown.Value = 'X';
            app.ConnectArduinoButton = uibutton(app.UIFigure, 'push');
            app.ConnectArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectArduinoButtonPushed, true);
            app.ConnectArduinoButton.Text = 'Connect Arduino';
            app.AngledegreesLabel_2 = uilabel(app.UIFigure);
            app.AngledegreesLabel_2.Text = 'Angle (degrees)';
            app.ThetaEditFieldLabel = uilabel(app.UIFigure);
            app.ThetaEditFieldLabel.Text = 'Theta:';
            app.ThetaEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThetaEditField.ValueChangedFcn = createCallbackFcn(app, @ThetaEditFieldValueChanged, true);
            app.ThetaButton = uibutton(app.UIFigure, 'push');
            app.ThetaButton.ButtonPushedFcn = createCallbackFcn(app, @ThetaButtonPushed, true);
            app.ThetaButton.Text = 'Theta+';
            app.ThetaButton_2 = uibutton(app.UIFigure, 'push');
            app.ThetaButton_2.ButtonPushedFcn = createCallbackFcn(app, @ThetaButton_2Pushed, true);
            app.ThetaButton_2.Text = 'Theta-';
            app.PDEditFieldLabel = uilabel(app.UIFigure);
            app.PDEditFieldLabel.Text = 'PD';
            app.PDEditField = uieditfield(app.UIFigure, 'numeric');
            app.PDEditField.ValueChangedFcn = createCallbackFcn(app, @PDEditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = MechanicalScan11

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end