classdef MechanicalScan1111 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        YButton                 matlab.ui.control.Button
        YButton_2               matlab.ui.control.Button
        XButton                 matlab.ui.control.Button
        XButton_2               matlab.ui.control.Button
        XEditFieldLabel         matlab.ui.control.Label
        XEditField              matlab.ui.control.NumericEditField
        YEditFieldLabel         matlab.ui.control.Label
        YEditField              matlab.ui.control.NumericEditField
        IncrementSliderLabel    matlab.ui.control.Label
        IncrementSlider         matlab.ui.control.Slider
        NDimensionalScanPanel   matlab.ui.container.Panel
        PerformScanButton       matlab.ui.control.StateButton
        StartEditFieldLabel     matlab.ui.control.Label
        StartEditField          matlab.ui.control.NumericEditField
        StopEditFieldLabel      matlab.ui.control.Label
        StopEditField           matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel  matlab.ui.control.Label
        StepSizeEditField       matlab.ui.control.NumericEditField
        NStepsEditFieldLabel    matlab.ui.control.Label
        NStepsEditField         matlab.ui.control.NumericEditField
        AngledegreesLabel       matlab.ui.control.Label
        AxisDropDownLabel       matlab.ui.control.Label
        AxisDropDown            matlab.ui.control.DropDown
        XButton_3               matlab.ui.control.StateButton
        YButton_3               matlab.ui.control.StateButton
        ThetaButton_3           matlab.ui.control.StateButton
        HeadScanButton          matlab.ui.control.StateButton
        ConnectArduinoButton    matlab.ui.control.Button
        AngledegreesLabel_2     matlab.ui.control.Label
        ThetaEditFieldLabel     matlab.ui.control.Label
        ThetaEditField          matlab.ui.control.NumericEditField
        ThetaButton             matlab.ui.control.Button
        ThetaButton_2           matlab.ui.control.Button
        PDEditFieldLabel        matlab.ui.control.Label
        PDEditField             matlab.ui.control.NumericEditField
        EnableYButton           matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        connected_arduino % Description
        x_pos
        x_step_pin
        x_dir_pin
        y_pos
        y_step_pin
        y_dir_pin
        theta
        theta_step_pin
        theta_dir_pin
        increment 
        scale
        direction
        ND_scan
        pd
        current_position
        position_steps
        positions_cell
        output_file_dir;
        output_filename_base;
    end
    
    methods (Access = private)
        
        function update_textfields(app)
            app.XEditField.Value = app.x_pos;
            app.YEditField.Value = app.y_pos;
            app.ThetaEditField.Value = app.theta; 
        end
        
        function get_step_size(app, value)
            app.ND_scan.step_size_n(app.ND_scan.axis_i) = floor(value/app.scale(app.ND_scan.axis_i));
            value = app.ND_scan.step_size_n(app.ND_scan.axis_i)*app.scale(app.ND_scan.axis_i);
            app.ND_scan.step_size(app.ND_scan.axis_i) = value;
        end
        
        function get_app_positions(app)
            locs = cell(1,3);
            for i = 1:3
                if app.ND_scan.axis_on(i)
                    locs{i} = zeros([1,app.ND_scan.steps(i)]);            
                    for j = 1:app.ND_scan.steps(i)
                        if app.ND_scan.start(i)< app.ND_scan.stop(i)
                            sign =1;
                        else
                            sign = -1;
                        end
                        locs{i}(j) = app.ND_scan.start(i) + sign*j*app.ND_scan.step_size(i);
                    end
                    locs{i} = [app.ND_scan.start(i), locs{i}];
                else
                    locs{i} = app.ND_scan.start(i);
                end
            end
            [LOCS1,LOCS2,LOCS3] = ndgrid(locs{1},locs{2},locs{3});
            app.ND_scan.positions = get_positions(LOCS1,LOCS2,LOCS3);
            if all(app.ND_scan.axis_on(1:2)) && app.HeadScanButton.Value
                app.ND_scan.positions = reorder_pos(app.ND_scan.positions);
                app = defineHeadScan(app, locs);
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.x_step_pin = 'D6';
            app.x_dir_pin = 'D7';
            
            app.y_step_pin = 'D11';
            app.y_dir_pin = 'D12';
            
            app.theta_step_pin = 'D2';
            app.theta_dir_pin = 'D3';
            
            app.x_pos = 0;
            app.y_pos = 0;
            app.theta = 0;
            app.scale = [7.82, 5, 180]/200; % X, Y, Theta [mm, mm, degrees]/step
            
            app.IncrementSlider.Limits = [1,200];
            app.increment = 20;
            app.IncrementSlider.Value = app.increment;
            app.IncrementSliderLabel.Text = strcat(['Increment: ',num2str(app.increment),' Steps']);
            
            app.XEditFieldLabel.Text = 'X (mm): ';
            app.YEditFieldLabel.Text = 'Y (mm): ';
            
            app.ND_scan = struct();
            app.ND_scan.start = zeros(1,3);
            app.ND_scan.stop = zeros(1,3);
            app.ND_scan.steps = zeros(1,3);
            app.ND_scan.step_size = zeros(1,3);
            app.ND_scan.axes = {'X','Y','Theta'};
            app.ND_scan.axes_ind = [1, 2, 3];
            app.ND_scan.axis_on = [0,0,0];
            app.ND_scan.axis = 'X';
            app.ND_scan.axis_i = 1;
            app.ND_scan.positions = [];
            app.AngledegreesLabel.Text = 'Position (mm)';
            app.pd = 10000;
            app.current_position= [0,0,0];
            app.position_steps = zeros(1,3);
            position_cell = cell(1,4);
            app.output_file_dir = ['C:\Users\Verasonics\Documents\VerasonicsScanFiles\MechanicalScan\3DScan11Rectangle\'];
            if ~exist(app.output_file_dir, 'dir')
                mkdir(app.output_file_dir)
            end
            app.output_filename_base = [app.output_file_dir,'TxRx_scan'];
            assignin('base','app',app);
        end

        % Button pushed function: ConnectArduinoButton
        function ConnectArduinoButtonPushed(app, event)
            try
                if ~isempty(instrfind)
                    fclose(instrfind);
                    delete(instrfind);
                end
                app.ConnectArduinoButton.BackgroundColor = [1, 1, 1]/2;
                app.connected_arduino = serial('COM4','BaudRate',9600);%'COM4'('/dev/cu.usbmodem1421','BaudRate',9600);
                fopen(app.connected_arduino);
                pause(2);
                app.ConnectArduinoButton.BackgroundColor = [0, 0, 1];
            catch e
                %Display error message
                app.ConnectArduinoButton.BackgroundColor = [1, 0, 0];
                disp(e.message);
            end
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            app.YButton.BackgroundColor = [0 0 1];
%             stepper_motor_inc(0,app.connected_arduino, 1, 1, 0, 0);
            stepper_motor_inc(app.increment,app.connected_arduino, app.y_dir_pin, app.y_step_pin, 1, 1000);
%             stepper_motor_inc(0,app.connected_arduino, 0, 0, 0, 0);
            app.y_pos = app.y_pos + app.increment*app.scale(2); 
            app.YButton.BackgroundColor = [0.96 0.96 0.96];
            update_textfields(app)
        end

        % Button pushed function: YButton_2
        function YButton_2Pushed(app, event)
            app.YButton_2.BackgroundColor = [0 0 1];
%             stepper_motor_inc(0,app.connected_arduino, 1, 1, 0, 0);
            stepper_motor_inc(app.increment,app.connected_arduino, app.y_dir_pin, app.y_step_pin, 0, 1000);
%             stepper_motor_inc(0,app.connected_arduino, 0, 0, 0, 0);
            app.y_pos = app.y_pos - app.increment*app.scale(2); 
            app.YButton_2.BackgroundColor = [0.96 0.96 0.96];
            update_textfields(app)
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            app.XButton.BackgroundColor = [0 0 1];
            stepper_motor_inc(app.increment,app.connected_arduino, app.x_dir_pin, app.x_step_pin, 1);
            app.x_pos = app.x_pos + app.increment*app.scale(1); 
            app.XButton.BackgroundColor = [0.96 0.96 0.96];
            update_textfields(app)
        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            app.XButton_2.BackgroundColor = [0 0 1];
            stepper_motor_inc(app.increment,app.connected_arduino, app.x_dir_pin, app.x_step_pin, 0);
            app.x_pos = app.x_pos - app.increment*app.scale(1);
            app.XButton_2.BackgroundColor = [0.96 0.96 0.96];
            update_textfields(app)
        end

        % Value changed function: IncrementSlider
        function IncrementSliderValueChanged(app, event)
            value = app.IncrementSlider.Value;
            ticks = [app.IncrementSlider.Limits(1):app.IncrementSlider.Limits(2)];
            [~,i] = min(abs(ticks-value));
            app.IncrementSlider.Value = ticks(i);
            app.increment = app.IncrementSlider.Value;
            app.IncrementSliderLabel.Text = strcat(['Increment: ', num2str(app.increment), ' Steps']);
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            app.x_pos = app.XEditField.Value;
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            app.y_pos = app.YEditField.Value;
        end

        % Value changed function: StartEditField
        function StartEditFieldValueChanged(app, event)
            app.ND_scan.start(app.ND_scan.axis_i) = app.StartEditField.Value;
        end

        % Value changed function: StopEditField
        function StopEditFieldValueChanged(app, event)
            app.ND_scan.stop(app.ND_scan.axis_i) = app.StopEditField.Value;
        end

        % Value changed function: StepSizeEditField
        function StepSizeEditFieldValueChanged(app, event)
            value = app.StepSizeEditField.Value;
            get_step_size(app, value);
            app.ND_scan.steps(app.ND_scan.axis_i) = floor(abs(app.ND_scan.stop(app.ND_scan.axis_i)-app.ND_scan.start(app.ND_scan.axis_i))/value);
            app.StepSizeEditField.Value = app.ND_scan.step_size(app.ND_scan.axis_i);
            app.NStepsEditField.Value = app.ND_scan.steps(app.ND_scan.axis_i);
        end

        % Value changed function: NStepsEditField
        function NStepsEditFieldValueChanged(app, event)
            app.ND_scan.steps(app.ND_scan.axis_i) = app.NStepsEditField.Value;
            value = abs(app.ND_scan.stop(app.ND_scan.axis_i) - app.ND_scan.start(app.ND_scan.axis_i))/app.ND_scan.steps(app.ND_scan.axis_i); 
            get_step_size(app, value);
            app.StepSizeEditField.Value = app.ND_scan.step_size(app.ND_scan.axis_i);
        end

        % Value changed function: PerformScanButton
        function PerformScanButtonValueChanged(app, event)
            app.current_position = [app.x_pos, app.y_pos, app.theta];
            disp(app.current_position)
            disp(app.ND_scan.start)
            get_app_positions(app)
            assignin('base','app',app);
            if app.HeadScanButton.Value
                basename = app.output_filename_base;
                if 0
                    headScanRegions = 2;
                else
                    headScanRegions = length(app.positions_cell);
                end
                for i = 1:headScanRegions %length(app.positions_cell)
                    app.output_filename_base = [basename,'_',num2str(i),'_'];
                    if i == 3
                        d_steps = move_positioner(app, app.current_position,app.positions_cell{i}(1,:), 0, 3);
                    else
                        d_steps = move_positioner(app, app.current_position,app.positions_cell{i}(1,:), 0);
                    end
                    pause(5);
                    app.position_steps(end+1,:) = d_steps;
                    app.ND_scan.positions = app.positions_cell{i};
                    app = mechanical_scan(app);
                    app.current_position = app.ND_scan.positions(end,:);
                end
            else
                d_steps = move_positioner(app, app.current_position,app.ND_scan.start);
                app.position_steps(end+1,:) = d_steps; 
                mechanical_scan(app);  
            end
        end

        % Value changed function: AxisDropDown
        function AxisDropDownValueChanged(app, event)
            app.ND_scan.axis = app.AxisDropDown.Value;
            [~, app.ND_scan.axis_i] = ismember(app.ND_scan.axis, app.ND_scan.axes);
            if strcmp(app.ND_scan.axis, 'Theta')
                app.AngledegreesLabel.Text = 'Angle (degrees)';
            else
                app.AngledegreesLabel.Text = 'Position (mm)';
            end
            app.StartEditField.Value = app.ND_scan.start(app.ND_scan.axis_i);
            app.StopEditField.Value = app.ND_scan.stop(app.ND_scan.axis_i);
            app.StepSizeEditField.Value = app.ND_scan.step_size(app.ND_scan.axis_i);
            app.NStepsEditField.Value = app.ND_scan.steps(app.ND_scan.axis_i);
            
        end

        % Button pushed function: ThetaButton
        function ThetaButtonPushed(app, event)
            app.ThetaButton.BackgroundColor = [0 0 1];
            stepper_motor_inc(app.increment,app.connected_arduino, app.theta_dir_pin, app.theta_step_pin, 1, app.pd);
            app.theta = app.theta + app.increment*app.scale(3); 
            app.ThetaButton.BackgroundColor = [0.96 0.96 0.96];
            update_textfields(app)
        end

        % Button pushed function: ThetaButton_2
        function ThetaButton_2Pushed(app, event)
            app.ThetaButton_2.BackgroundColor = [0 0 1];
            stepper_motor_inc(app.increment,app.connected_arduino, app.theta_dir_pin, app.theta_step_pin,0, app.pd);
            app.theta = app.theta - app.increment*app.scale(3); 
            app.ThetaButton_2.BackgroundColor = [0.96 0.96 0.96];
            update_textfields(app)
        end

        % Value changed function: ThetaEditField
        function ThetaEditFieldValueChanged(app, event)
            app.theta = app.ThetaEditField.Value;           
        end

        % Value changed function: PDEditField
        function PDEditFieldValueChanged(app, event)
            app.pd = app.PDEditField.Value;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
            fclose(app.connected_arduino);
            catch
            end
            delete(instrfind);
            delete(app);
            
            
        end

        % Value changed function: XButton_3
        function XButton_3ValueChanged(app, event)
            app.ND_scan.axis_on(1) = double(app.XButton_3.Value);
        end

        % Value changed function: YButton_3
        function YButton_3ValueChanged(app, event)
            app.ND_scan.axis_on(2) = double(app.YButton_3.Value);
        end

        % Value changed function: ThetaButton_3
        function ThetaButton_3ValueChanged(app, event)
            app.ND_scan.axis_on(3) = double(app.ThetaButton_3.Value);
        end

        % Value changed function: HeadScanButton
        function HeadScanButtonValueChanged(app, event)
            value = app.HeadScanButton.Value;
        end

        % Value changed function: EnableYButton
        function EnableYButtonValueChanged(app, event)
            value = app.EnableYButton.Value;
            if value
                stepper_motor_inc(0,app.connected_arduino, 0, 0, 0, 0);
            else
                stepper_motor_inc(0,app.connected_arduino, 1, 1, 0, 0);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.YButton = uibutton(app.UIFigure, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = 'Y+';
            app.YButton_2 = uibutton(app.UIFigure, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YButton_2Pushed, true);
            app.YButton_2.Text = 'Y-';
            app.XButton = uibutton(app.UIFigure, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X+';
            app.XButton_2 = uibutton(app.UIFigure, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = 'X-';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X:';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y:';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.IncrementSliderLabel = uilabel(app.UIFigure);
            app.IncrementSliderLabel.Text = 'Increment: ';
            app.IncrementSlider = uislider(app.UIFigure);
            app.IncrementSlider.ValueChangedFcn = createCallbackFcn(app, @IncrementSliderValueChanged, true);
            app.NDimensionalScanPanel = uipanel(app.UIFigure);
            app.NDimensionalScanPanel.Title = 'N Dimensional Scan';
            app.PerformScanButton = uibutton(app.NDimensionalScanPanel, 'state');
            app.PerformScanButton.ValueChangedFcn = createCallbackFcn(app, @PerformScanButtonValueChanged, true);
            app.PerformScanButton.Text = 'Perform Scan';
            app.StartEditFieldLabel = uilabel(app.NDimensionalScanPanel);
            app.StartEditFieldLabel.Text = 'Start:';
            app.StartEditField = uieditfield(app.NDimensionalScanPanel, 'numeric');
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @StartEditFieldValueChanged, true);
            app.StopEditFieldLabel = uilabel(app.NDimensionalScanPanel);
            app.StopEditFieldLabel.Text = 'Stop:';
            app.StopEditField = uieditfield(app.NDimensionalScanPanel, 'numeric');
            app.StopEditField.ValueChangedFcn = createCallbackFcn(app, @StopEditFieldValueChanged, true);
            app.StepSizeEditFieldLabel = uilabel(app.NDimensionalScanPanel);
            app.StepSizeEditFieldLabel.Text = 'Step Size:';
            app.StepSizeEditField = uieditfield(app.NDimensionalScanPanel, 'numeric');
            app.StepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @StepSizeEditFieldValueChanged, true);
            app.NStepsEditFieldLabel = uilabel(app.NDimensionalScanPanel);
            app.NStepsEditFieldLabel.Text = 'N Steps:';
            app.NStepsEditField = uieditfield(app.NDimensionalScanPanel, 'numeric');
            app.NStepsEditField.ValueChangedFcn = createCallbackFcn(app, @NStepsEditFieldValueChanged, true);
            app.AngledegreesLabel = uilabel(app.NDimensionalScanPanel);
            app.AngledegreesLabel.Text = 'Angle (degrees)';
            app.AxisDropDownLabel = uilabel(app.NDimensionalScanPanel);
            app.AxisDropDownLabel.Text = 'Axis';
            app.AxisDropDown = uidropdown(app.NDimensionalScanPanel);
            app.AxisDropDown.Items = {'X', 'Y', 'Theta', ''};
            app.AxisDropDown.ValueChangedFcn = createCallbackFcn(app, @AxisDropDownValueChanged, true);
            app.AxisDropDown.Value = 'X';
            app.XButton_3 = uibutton(app.NDimensionalScanPanel, 'state');
            app.XButton_3.ValueChangedFcn = createCallbackFcn(app, @XButton_3ValueChanged, true);
            app.XButton_3.Text = 'X';
            app.YButton_3 = uibutton(app.NDimensionalScanPanel, 'state');
            app.YButton_3.ValueChangedFcn = createCallbackFcn(app, @YButton_3ValueChanged, true);
            app.YButton_3.Text = 'Y';
            app.ThetaButton_3 = uibutton(app.NDimensionalScanPanel, 'state');
            app.ThetaButton_3.ValueChangedFcn = createCallbackFcn(app, @ThetaButton_3ValueChanged, true);
            app.ThetaButton_3.Text = 'Theta';
            app.HeadScanButton = uibutton(app.NDimensionalScanPanel, 'state');
            app.HeadScanButton.ValueChangedFcn = createCallbackFcn(app, @HeadScanButtonValueChanged, true);
            app.HeadScanButton.Text = 'Head Scan';
            app.ConnectArduinoButton = uibutton(app.UIFigure, 'push');
            app.ConnectArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectArduinoButtonPushed, true);
            app.ConnectArduinoButton.Text = 'Connect Arduino';
            app.AngledegreesLabel_2 = uilabel(app.UIFigure);
            app.AngledegreesLabel_2.Text = 'Angle (degrees)';
            app.ThetaEditFieldLabel = uilabel(app.UIFigure);
            app.ThetaEditFieldLabel.Text = 'Theta:';
            app.ThetaEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThetaEditField.ValueChangedFcn = createCallbackFcn(app, @ThetaEditFieldValueChanged, true);
            app.ThetaButton = uibutton(app.UIFigure, 'push');
            app.ThetaButton.ButtonPushedFcn = createCallbackFcn(app, @ThetaButtonPushed, true);
            app.ThetaButton.Text = 'Theta+';
            app.ThetaButton_2 = uibutton(app.UIFigure, 'push');
            app.ThetaButton_2.ButtonPushedFcn = createCallbackFcn(app, @ThetaButton_2Pushed, true);
            app.ThetaButton_2.Text = 'Theta-';
            app.PDEditFieldLabel = uilabel(app.UIFigure);
            app.PDEditFieldLabel.Text = 'PD';
            app.PDEditField = uieditfield(app.UIFigure, 'numeric');
            app.PDEditField.ValueChangedFcn = createCallbackFcn(app, @PDEditFieldValueChanged, true);
            app.EnableYButton = uibutton(app.UIFigure, 'state');
            app.EnableYButton.ValueChangedFcn = createCallbackFcn(app, @EnableYButtonValueChanged, true);
            app.EnableYButton.Text = 'EnableY';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MechanicalScan1111

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end